classdef drawthing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        HTML               matlab.ui.control.HTML
        FileExplorerPanel  matlab.ui.container.Panel
        Tree               matlab.ui.container.Tree
        Node               matlab.ui.container.TreeNode
        Viewer             matlab.ui.container.Panel
        ContextMenu        matlab.ui.container.ContextMenu
        OpenMenu           matlab.ui.container.Menu
        SketchMenu         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Img % Inbound image
        netR = load('dualnetAngleApp.mat','netR').netR;
        netC = load('dualnetAngleApp.mat','netC').netC;
    end
    
    methods (Access = private)
        
        function setupTree(app)
            MyFolderInfo = dir; 
            MyFolderInfo = MyFolderInfo(~cellfun('isempty', {MyFolderInfo.date}));
            S = struct2table(MyFolderInfo);
            
            % First level nodes
            category1 = uitreenode(app.Tree,'Text','..','NodeData',MyFolderInfo(1).folder,'Icon','upfolder.gif','ContextMenu',app.ContextMenu);  % folderinfo should be const
            category2 = uitreenode(app.Tree,'Text','.','NodeData',MyFolderInfo(1).folder,'Icon','foldericon.gif','ContextMenu',app.ContextMenu);

            S(1:2,:) = []; % these are nodes above
            [~,b,c] = fileparts(S.name);
            c = string(c);
            isfold = c==""; % no file extensions
            F = S(isfold,:);
            ispic = or(c==".png",c==".gif");
            notpic = S(and(~ispic,~isfold),:); % use plain icon for all else
            pic = S(ispic,:);
            % Second level nodes.
            % Node data will be File paths
            cellfun(@(x,y) uitreenode(category2,'Text',x,'NodeData',y,'Icon','foldericon.gif','ContextMenu',app.ContextMenu),F.name,F.folder);
            cellfun(@(x,y) uitreenode(category2,'Text',x,'NodeData',y,'Icon','paintbrush.gif'),pic.name,pic.folder);
            cellfun(@(x,y) uitreenode(category2,'Text',x,'NodeData',y,'Icon','pageicon.gif'),notpic.name,notpic.folder);
                

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Tree.Children.delete;
            setupTree(app)
            app.Tree.SelectedNodes = app.Tree.Children(1);
            tl = tiledlayout(1,1,'Parent',app.Viewer); % seems faster?
%             t = nexttile(tl);
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            data = struct2cell(data.data);
            data = cell2mat(data);
            data = data(1:4:end); % [rgba means 1 in 4 needed]
            w = sqrt(numel(data));
            data = reshape(data,[w w]);
            
%             scale = 28 ./ w;
            Img = fliplr(imresize(data,[28 28])); % flip and rotate
            Img = imrotate(Img,90);
             
            app.Img = Img; 
            tl = tiledlayout(1,1,'Parent',app.Viewer)
            t = nexttile(tl);
            imshow(app.Img,'Parent',t)
            p = classify(app.netC,Img);
            pr = predict(app.netR,Img);
            pr = rem(pr,360);
            title(t, "Class: " + string(p) + newline + "angle: " + num2str(pr))
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            [~,b,c] = fileparts(selectedNodes.Text);
            if or(strcmpi(c,'.png'),strcmpi(c,'.gif'))
                app.Img = imread(strcat(selectedNodes.NodeData,"\",selectedNodes.Text))
                tl = tiledlayout(1,1,'Parent',app.Viewer)
                t = nexttile(tl);
                imshow(app.Img,'Parent',t)
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            try
            selectedNodes = app.Tree.SelectedNodes;
            cd(strcat(selectedNodes.NodeData,"\",selectedNodes.Text))
            app.Tree.Children.delete;
            setupTree(app)
            catch
                uialert(app.UIFigure,'Issue opening, ensure subfolder selected','Cannot open subfolder','Icon','info')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            app.HTML.HTMLSource = 'Copy_of_drawthing.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.FileExplorerPanel = uipanel(app.GridLayout);
            app.FileExplorerPanel.Title = 'File Explorer';
            app.Tree = uitree(app.FileExplorerPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Viewer = uipanel(app.GridLayout);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Node.ContextMenu = app.ContextMenu;
            app.OpenMenu = uimenu(app.ContextMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SketchMenu = uimenu(app.ContextMenu);
            app.SketchMenu.Text = 'Sketch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawthing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end